classdef orthogonalTemperatureViewsGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        cor                 matlab.ui.control.UIAxes
        sag                 matlab.ui.control.UIAxes
        ax                  matlab.ui.control.UIAxes
        windowUp            matlab.ui.control.Button
        MaxTLabel           matlab.ui.control.Label
        windowDown          matlab.ui.control.Button
        corLeft             matlab.ui.control.Button
        corRight            matlab.ui.control.Button
        sagLeft             matlab.ui.control.Button
        sagRight            matlab.ui.control.Button
        axLeft              matlab.ui.control.Button
        axRight             matlab.ui.control.Button
        corSlider           matlab.ui.control.Slider
        sagSlider           matlab.ui.control.Slider
        axSlider            matlab.ui.control.Slider
        selectFocus         matlab.ui.control.Button
        done                matlab.ui.control.Button
        FocusLabel          matlab.ui.control.Label
        tTime               matlab.ui.control.UIAxes
        tAxes               matlab.ui.control.UIAxes
        PlotOverTimeButton  matlab.ui.control.Button
        dynamicUp           matlab.ui.control.Button
        DynamicLabel        matlab.ui.control.Label
        dynamicDown         matlab.ui.control.Button
    end


    methods (Access = private)
    
        function drawMrImages(app)
            tic
            global sys
            img = sys.aImg;
            xyz = sys.xyzAxes;
            if ~isfield(sys,'tInterp')
                error('You must create the interpolated thermometry image and store is in sys.tInterp.')
            end
            tImg = sys.tInterp;
            dynamic = sys.dynamic;
            tWindow = sys.tWindow;
            
            % Pick slices
            corSlice = app.corSlider.Value;
            sagSlice = app.sagSlider.Value;
            axSlice = app.axSlider.Value;
            
            % Create slice images with white line to show where other slices are
            % Coronal
            corImg = squeeze(img(:,corSlice,:)).';
            tCorImg = squeeze(tImg(:,corSlice,:,dynamic)).';
            
            corImg(:,sagSlice) = max(corImg(:));
            corImg(axSlice,:) = max(corImg(:));

            % Sagital
            sagImg = squeeze(img(sagSlice,:,:)).';
            tSagImg = squeeze(tImg(sagSlice,:,:,dynamic)).';
            sagImg(:,corSlice) = max(sagImg(:));
            sagImg(axSlice,:) = max(sagImg(:));
            
            % Axial
            axImg = squeeze(img(:,:,axSlice)).';
            tAxImg = squeeze(tImg(:,:,axSlice,dynamic)).';
            axImg(corSlice,:) = max(axImg(:));
            axImg(:,sagSlice) = max(axImg(:));
            toc
            overlayImages2(corImg,tCorImg,[min(corImg(:)),max(corImg(:))],tWindow,xyz(1,:),xyz(3,:),app.cor);
            toc
            overlayImages2(sagImg,tSagImg,[min(sagImg(:)),max(sagImg(:))],tWindow,xyz(2,:),xyz(3,:),app.sag);
            toc
            overlayImages2(axImg,tAxImg,[min(axImg(:)),max(axImg(:))],tWindow,xyz(1,:),xyz(2,:),app.ax);
            toc
            
            cla(app.tTime);
            cla(app.tAxes);
            drawnow
            
            % Reset focus label based on current slices
            txCenter = [sys.ux(sys.txCenterIdx(1)),sys.uy(sys.txCenterIdx(2)),sys.uz(sys.txCenterIdx(3))];
            focus = [sys.ux(sagSlice),sys.uy(corSlice),sys.uz(axSlice)];
            app.FocusLabel.Text = ['Focus: ', num2str((focus-txCenter)*1e3,2)];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, incomingSys)
            global sys
            sys = incomingSys;
            sys.window = [0,max(sys.aImg(:))];
            sys.tWindow = [0,5];
            img = sys.aImg;
            
            % Setup initial values: Slice sliders
            app.corSlider.Limits = [1,size(img,2)];
            app.corSlider.Value = sys.focalSpotIdx(2);
            
            app.sagSlider.Limits = [1,size(img,1)];
            app.sagSlider.Value = sys.focalSpotIdx(1);
            
            app.axSlider.Limits = [1,size(img,3)];
            app.axSlider.Value = sys.focalSpotIdx(3);
            
            % Setup initial values: Window
            app.MaxTLabel.Text = ['MaxT:',num2str(sys.tWindow(2))];
            app.DynamicLabel.Text = ['Dynamic:',num2str(sys.dynamic)];
            
            drawMrImages(app);
        end

        % Button pushed function: windowUp
        function windowUpButtonPushed(app, event)
            global sys;
                        
            sys.tWindow(2) = sys.tWindow(2)+1;
            app.MaxTLabel.Text = ['MaxT:',num2str(sys.tWindow(2))];
            drawMrImages(app);
        end

        % Button pushed function: windowDown
        function windowDownButtonPushed(app, event)
            global sys
            if sys.tWindow(2) <= 1
                return
            end
            
            sys.tWindow(2) = sys.tWindow(2)-1;
            app.MaxTLabel.Text = ['MaxT:',num2str(sys.tWindow(2))];
            drawMrImages(app);
        end

        % Button pushed function: corLeft
        function corLeftButtonPushed(app, event)
            if app.corSlider.Value<=app.corSlider.Limits(1)
                return
            else
                app.corSlider.Value = app.corSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: corRight
        function corRightButtonPushed(app, event)
            if app.corSlider.Value>=app.corSlider.Limits(2)
                return
            else
                app.corSlider.Value = app.corSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: sagLeft
        function sagLeftButtonPushed(app, event)
            if app.sagSlider.Value<=app.sagSlider.Limits(1)
                return
            else
                app.sagSlider.Value = app.sagSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: sagRight
        function sagRightButtonPushed(app, event)
            if app.sagSlider.Value>=app.sagSlider.Limits(2)
                return
            else
                app.sagSlider.Value = app.sagSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: axLeft
        function axLeftButtonPushed(app, event)
            if app.axSlider.Value<=app.axSlider.Limits(1)
                return
            else
                app.axSlider.Value = app.axSlider.Value-1;
                drawMrImages(app);
            end
        end

        % Button pushed function: axRight
        function axRightButtonPushed(app, event)
            if app.axSlider.Value>=app.axSlider.Limits(2)
                return
            else
                app.axSlider.Value = app.axSlider.Value+1;
                drawMrImages(app);
            end
        end

        % Button pushed function: done
        function doneButtonPushed(app, event)
            focalSpotIdx = [app.sagSlider.Value,app.corSlider.Value,app.axSlider.Value];
            save('guiFileOutput','focalSpotIdx');
            closereq();
        end

        % Button pushed function: selectFocus
        function selectFocusButtonPushed(app, event)
            global sys;
            xyz = sys.xyzAxes;
            img = sys.aImg;
            
            h = figure;
            corSlice = app.corSlider.Value;
            
            % Create slice images with white line to show where other slices are
            % Coronal
            corImg = squeeze(img(:,corSlice,:,:));
            corImg = permute(corImg,[2,1,3]);
            
            imshow(corImg,'xdata',xyz(1,:),'ydata',xyz(3,:));
            [focX,focZ] = ginput(1);
            
            [~,focX] = min(abs(focX-sys.ux));
            [~,focZ] = min(abs(focZ-sys.uz));
            
            app.sagSlider.Value = focX;
            app.axSlider.Value = focZ;
            close(h);
            
            drawMrImages(app);
        end

        % Value changed function: corSlider
        function corSliderValueChanged(app, event)
            value = app.corSlider.Value;
            app.corSlider.Value = round(value);
            drawMrImages(app);
        end

        % Value changed function: sagSlider
        function sagSliderValueChanged(app, event)
            value = app.sagSlider.Value;
            app.sagSlider.Value = round(value);
            drawMrImages(app);
        end

        % Value changed function: axSlider
        function axSliderValueChanged(app, event)
            value = app.axSlider.Value;
            app.axSlider.Value = round(value);
            drawMrImages(app);
        end

        % Button pushed function: PlotOverTimeButton
        function PlotOverTimeButtonPushed(app, event)
            global sys
            tImg = sys.tInterp;
            
            % Pick slices
            corSlice = app.corSlider.Value;
            sagSlice = app.sagSlider.Value;
            axSlice = app.axSlider.Value;
            
            curCenter = [sagSlice,corSlice,axSlice];
            x = sys.ax - sys.ax(curCenter(1));
            y = sys.ay - sys.ay(curCenter(2));
            z = sys.az - sys.az(curCenter(3));
            
            [Y,X,Z] = meshgrid(y,x,z);
            R = sqrt(X.^2+Y.^2+Z.^2);
            roi = zeros(size(R));
            roi(R<=2.5e-3) = 1;
            roi = logical(roi);
            tTimeAvg = zeros(1,size(tImg,4));
            tTimeMax = tTimeAvg;
            for ii = 1:size(tImg,4)
                curT = tImg(:,:,:,ii);
                tTimeAvg(ii) = mean(curT(roi));
                tTimeMax(ii) = max(curT(roi));
            end
            
            plot(app.tTime,1:size(tImg,4),tTimeAvg,'-',1:size(tImg,4),tTimeMax,'-','linewidth',2);
            legend(app.tTime,'Average','Max');
            grid on;
            
            plot(app.tAxes,x*1e3,tImg(:,corSlice,axSlice,sys.dynamic),'-',...
                y*1e3,tImg(sagSlice,:,axSlice,sys.dynamic),'-',...
                z*1e3,squeeze(tImg(sagSlice,corSlice,:,sys.dynamic)),'-','linewidth',2)
            legend(app.tAxes,'x','y','z')
            axis(app.tAxes,[-3,3,0,sys.tWindow(2)]);
            grid on
            
            clear roi X Y Z x y z R
            
        end

        % Button pushed function: dynamicUp
        function dynamicUpButtonPushed(app, event)
            global sys
            if sys.dynamic >= size(sys.tInterp,4)
                sys.dynamic = size(sys.tInterp,4);
            else
                sys.dynamic = sys.dynamic+1;
            end
            app.DynamicLabel.Text = ['Dynamic:',num2str(sys.dynamic)];
            drawMrImages(app);
        end

        % Button pushed function: dynamicDown
        function dynamicDownButtonPushed(app, event)
            global sys
            if sys.dynamic <= 1
                sys.dynamic = 1;
            else
                sys.dynamic = sys.dynamic-1;
            end
            app.DynamicLabel.Text = ['Dynamic:',num2str(sys.dynamic)];
            drawMrImages(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.cor = uiaxes(app.UIFigure);
            title(app.cor, 'Coronal')
            app.sag = uiaxes(app.UIFigure);
            title(app.sag, 'Sagital')
            app.ax = uiaxes(app.UIFigure);
            title(app.ax, 'Axial')
            app.windowUp = uibutton(app.UIFigure, 'push');
            app.windowUp.ButtonPushedFcn = createCallbackFcn(app, @windowUpButtonPushed, true);
            app.windowUp.Text = '^';
            app.MaxTLabel = uilabel(app.UIFigure);
            app.MaxTLabel.Text = 'MaxT';
            app.windowDown = uibutton(app.UIFigure, 'push');
            app.windowDown.ButtonPushedFcn = createCallbackFcn(app, @windowDownButtonPushed, true);
            app.windowDown.Text = 'v';
            app.corLeft = uibutton(app.UIFigure, 'push');
            app.corLeft.ButtonPushedFcn = createCallbackFcn(app, @corLeftButtonPushed, true);
            app.corLeft.Text = '<';
            app.corRight = uibutton(app.UIFigure, 'push');
            app.corRight.ButtonPushedFcn = createCallbackFcn(app, @corRightButtonPushed, true);
            app.corRight.Text = '>';
            app.sagLeft = uibutton(app.UIFigure, 'push');
            app.sagLeft.ButtonPushedFcn = createCallbackFcn(app, @sagLeftButtonPushed, true);
            app.sagLeft.Text = '<';
            app.sagRight = uibutton(app.UIFigure, 'push');
            app.sagRight.ButtonPushedFcn = createCallbackFcn(app, @sagRightButtonPushed, true);
            app.sagRight.Text = '>';
            app.axLeft = uibutton(app.UIFigure, 'push');
            app.axLeft.ButtonPushedFcn = createCallbackFcn(app, @axLeftButtonPushed, true);
            app.axLeft.Text = '<';
            app.axRight = uibutton(app.UIFigure, 'push');
            app.axRight.ButtonPushedFcn = createCallbackFcn(app, @axRightButtonPushed, true);
            app.axRight.Text = '>';
            app.corSlider = uislider(app.UIFigure);
            app.corSlider.MajorTicks = [];
            app.corSlider.MajorTickLabels = {};
            app.corSlider.ValueChangedFcn = createCallbackFcn(app, @corSliderValueChanged, true);
            app.corSlider.MinorTicks = [];
            app.sagSlider = uislider(app.UIFigure);
            app.sagSlider.MajorTicks = [];
            app.sagSlider.MajorTickLabels = {};
            app.sagSlider.ValueChangedFcn = createCallbackFcn(app, @sagSliderValueChanged, true);
            app.sagSlider.MinorTicks = [];
            app.axSlider = uislider(app.UIFigure);
            app.axSlider.MajorTicks = [];
            app.axSlider.MajorTickLabels = {};
            app.axSlider.ValueChangedFcn = createCallbackFcn(app, @axSliderValueChanged, true);
            app.axSlider.MinorTicks = [];
            app.selectFocus = uibutton(app.UIFigure, 'push');
            app.selectFocus.ButtonPushedFcn = createCallbackFcn(app, @selectFocusButtonPushed, true);
            app.selectFocus.Text = 'Select Focus';
            app.done = uibutton(app.UIFigure, 'push');
            app.done.ButtonPushedFcn = createCallbackFcn(app, @doneButtonPushed, true);
            app.done.Text = 'Done';
            app.FocusLabel = uilabel(app.UIFigure);
            app.FocusLabel.Text = 'Focus:';
            app.tTime = uiaxes(app.UIFigure);
            title(app.tTime, 'Temperature over time')
            xlabel(app.tTime, 'Dynamic')
            ylabel(app.tTime, 'Temperature (C)')
            app.tAxes = uiaxes(app.UIFigure);
            title(app.tAxes, 'Cut along axes')
            xlabel(app.tAxes, 'Distance from center (mm)')
            ylabel(app.tAxes, 'Temperature (C)')
            app.PlotOverTimeButton = uibutton(app.UIFigure, 'push');
            app.PlotOverTimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOverTimeButtonPushed, true);
            app.PlotOverTimeButton.Text = 'Plot Over Time';
            app.dynamicUp = uibutton(app.UIFigure, 'push');
            app.dynamicUp.ButtonPushedFcn = createCallbackFcn(app, @dynamicUpButtonPushed, true);
            app.dynamicUp.Text = '^';
            app.DynamicLabel = uilabel(app.UIFigure);
            app.DynamicLabel.Text = 'Dynamic';
            app.dynamicDown = uibutton(app.UIFigure, 'push');
            app.dynamicDown.ButtonPushedFcn = createCallbackFcn(app, @dynamicDownButtonPushed, true);
            app.dynamicDown.Text = 'v';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orthogonalTemperatureViewsGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end